classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UIAxes             matlab.ui.control.UIAxes
        PlotButton         matlab.ui.control.Button
        L2EditFieldLabel   matlab.ui.control.Label
        L2EditField        matlab.ui.control.NumericEditField
        L3EditFieldLabel   matlab.ui.control.Label
        L3EditField        matlab.ui.control.NumericEditField
        L1EditFieldLabel   matlab.ui.control.Label
        L1EditField        matlab.ui.control.NumericEditField
        Q1minSpinnerLabel  matlab.ui.control.Label
        Q1minSpinner       matlab.ui.control.Spinner
        Q2minSpinnerLabel  matlab.ui.control.Label
        Q2minSpinner       matlab.ui.control.Spinner
        Q3minSpinnerLabel  matlab.ui.control.Label
        Q3minSpinner       matlab.ui.control.Spinner
        Q1maxSpinnerLabel  matlab.ui.control.Label
        Q1maxSpinner       matlab.ui.control.Spinner
        Q2maxSpinnerLabel  matlab.ui.control.Label
        Q2maxSpinner       matlab.ui.control.Spinner
        Q3maxSpinnerLabel  matlab.ui.control.Label
        Q3maxSpinner       matlab.ui.control.Spinner
    end


    properties (Access = private)
        L1 ;
        L2 ;
        L3 ;
        Q1_min;
        Q1_max ;
        Q2_min ;
        Q2_max ;
        Q3_min ;
        Q3_max ;
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.L1=app.L1EditField.Value;
            app.L2=app.L2EditField.Value;
            app.L3=app.L3EditField.Value;
            app.Q1_min=app.Q1minSpinner.Value;
            app.Q1_max=app.Q1maxSpinner.Value;
            app.Q2_min=app.Q2minSpinner.Value;
            app.Q2_max=app.Q2maxSpinner.Value;
            app.Q3_min=app.Q3minSpinner.Value;
            app.Q3_max=app.Q3maxSpinner.Value;
            t1=linspace(app.Q1_min, app.Q1_max, 100)*pi/180;
            t2=linspace(app.Q2_min, app.Q2_max, 100)*pi/180;
            t3=linspace(app.Q3_min, app.Q3_max, 100)*pi/180;
            [q1,q2,q3]=ndgrid(t1,t2,t3);  %This will create matrices of 120*120*120 for each variable
            xM = app.L1 * cos(q1) + app.L2 * cos(q1 + q2) + app.L3 * cos(q1 + q2 + q3);
            yM = app.L1 * sin(q1) + app.L2 * sin(q1 + q2) + app.L3 * sin(q1 + q2 + q3);
            plot(app.UIAxes,xM(:),yM(:));
            %             xL = xlim; %all values of x
            %             yL = ylim; %all values of y
            %             line(app.UIAxes,xL, [0 0],'color','k','linewidth',1)  %Draw x-axis
            %             line(app.UIAxes,[0 0], yL,'color','k','linewidth',1)  %Draw y-axis
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot WorkSpace');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q1minSpinnerLabel = uilabel(app.UIFigure);
            app.Q1minSpinnerLabel.Text = 'Q1 min';
            app.Q1minSpinner = uispinner(app.UIFigure);
            app.Q1minSpinner.Limits = [-360 360];
            app.Q2minSpinnerLabel = uilabel(app.UIFigure);
            app.Q2minSpinnerLabel.Text = 'Q2 min';
            app.Q2minSpinner = uispinner(app.UIFigure);
            app.Q2minSpinner.Limits = [-360 360];
            app.Q3minSpinnerLabel = uilabel(app.UIFigure);
            app.Q3minSpinnerLabel.Text = 'Q3 min';
            app.Q3minSpinner = uispinner(app.UIFigure);
            app.Q3minSpinner.Limits = [-360 360];
            app.Q1maxSpinnerLabel = uilabel(app.UIFigure);
            app.Q1maxSpinnerLabel.Text = 'Q1 max';
            app.Q1maxSpinner = uispinner(app.UIFigure);
            app.Q1maxSpinner.Limits = [-360 360];
            app.Q2maxSpinnerLabel = uilabel(app.UIFigure);
            app.Q2maxSpinnerLabel.Text = 'Q2 max';
            app.Q2maxSpinner = uispinner(app.UIFigure);
            app.Q2maxSpinner.Limits = [-360 360];
            app.Q3maxSpinnerLabel = uilabel(app.UIFigure);
            app.Q3maxSpinnerLabel.Text = 'Q3 max';
            app.Q3maxSpinner = uispinner(app.UIFigure);
            app.Q3maxSpinner.Limits = [-360 360];
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end